classdef SistemaEstacionamiento < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EstacionamientodeVehiculosUIFigure  matlab.ui.Figure
        SISTEMADEESTACIONAMIENTOLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ManualButton                   matlab.ui.control.Button
        ComenzarButton                 matlab.ui.control.Button
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComenzarButton
        function ComenzarButtonPushed(app, event)
           %% TÍTULO: SISTEMA DE ESTACIONAMIENTO
           %% NOMBRE: Yahir Angel Quispe Merchan
           %% Este programa permite establecer una guia ordenada para
           %% estacionar vehiculos mediante el uso de vectores 
           %% ingresando como datos las placas de los vehiculos

            %Declaracion de variables
            max = 5; %Tamaño del arreglo de vehiculos 
            aux = zeros(1,max); %Arreglo auxiliar para los autos 
            % del estacionamiento de dimension 1x5.
            tope = 1;  %contadores
            posicion = 1;%contadores
            autos = [];%Arreglo para los autos del estacionamiento 
            % de dimension 1x5.
            dato = ""; %Variable de tipo entero para los vehiculos ingresados
            autos = string(aux);% conversion de autos a string para asignar
            %las placas de los vehiculos

            % MENU CONTEXTUAL
            choice = true
            while choice ~= 4
            msg = "Bienvenido(a) a la recepción del estacionamiento. ¿Que actividad desea hacer?'";
            opts = ["Ingresar Vehículo" "Mostrar Vehículos Ingresados" "Salida de Vehículos" "Cerrar"];
            choice = menu(msg,opts);

              if choice == 1 % operaciones para el ingreso de vehiculos
                 if tope == 6
                     message = {'Estacionamiento Lleno, por favor espere que haya un lugar libre.'};
                     uialert(app.EstacionamientodeVehiculosUIFigure,message,'Ingreso de Vehiculos',...
                         'Icon','info');
                 else
                     answer = inputdlg('Por favor ingrese la placa del vehiculo:',...
                     'Ingreso de Vehiculos', [1 35])
                     message = {'Vehiculo ingresado exitosamente.'};
                     uialert(app.EstacionamientodeVehiculosUIFigure,message,'Ingreso de Vehiculos',...
                         'Icon','info');
                     dato = string(answer{1})
                     autos(posicion) = string(answer{1});
                     posicion = posicion + 1;
                     tope = posicion

                 app.TextArea.Value="";
                  for i=1:length(autos)
                      if autos(i) == "0"
                          autos(i) = "libre";
                      end
                 end
                 app.TextArea.Value=string(autos);

                 end

              elseif choice == 2 % operaciones para mostrar los vehiculos ingresados y espacios libres
                  app.TextArea.Value="";
                  for i=1:length(autos)
                      if autos(i) == "0"
                          autos(i) = "libre";
                      end
                 end
                 app.TextArea.Value=string(autos);


              elseif choice == 3% operaciones para la salida de vehiculos
                  app.TextArea.Value="";
                  app.TextArea.Value=string(autos);
                  answer = inputdlg('Por favor ingrese la placa del vehiculo que desea salir del estacionamiento. Hagalo de forma descendente:',...
                     'Salida de Vehiculos', [1 35])
                 dato = string(answer{1})
                
                 for i=1:length(autos)
                     if autos(i) == "0"
                         autos(i) = "libre";
                     end
                     if dato == autos(i)
                         autos(i) = "libre";
                         posicion = i;
                         tope = posicion
                     end

                 end
                 app.TextArea.Value=string(autos);


              elseif choice == 4 %salir del menu principal y borrar todos los
                  %vehiculos asignados
                  disp('Menu terminado')
              else
                  clc
              end

            end
            

        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
        manual = ['Manual de Ayuda' newline 'Para empezar a estacionar o sacar autos presione el botón Comenzar' newline '- Para Salir del programa completo puede hacer click en la X roja' newline "Uso del Programa:" newline "- Al presionar el botón Comenzar se ejecutara el menu principal del programa, donde usted introducira la opción de su preferecia ya sea  opcion1 para 'Ingresar Vehiculo'; opcion2 para 'Mostrar Vehiculos'; opcion3 para 'Salida de Vehiculo' y opcion4 para 'Cerrar' respectivamente el menu principal." newline "- Si da click en la opcion 1 el programa le pedira el número de la placa del auto que desea introducir." newline "- Si da click en la opcion 2 el programa le mostrara el estado del estacionamiento en tiempo real." newline "- Si da click en la opcion 3 el programa le pedira que ingrese la placa del vehiculo a salir del estacionamiento de forma descendente, empezando por la ultima placa ingresada y asi sucesivamente." newline "- Si da click en la opcion 4 el programa cerrara el menu principal del programa, borrando todos los datos ingresados." newline "Nota: Si elige la opción 4 sin haber terminado de introducir todos los datos, perdera los datos que haya introducido previamente."];

        app.TextArea.Value=manual;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EstacionamientodeVehiculosUIFigure = uifigure('Visible', 'off');
            app.EstacionamientodeVehiculosUIFigure.Name = 'Estacionamiento de Vehiculos';
            app.Image = uiimage(app.EstacionamientodeVehiculosUIFigure);
            app.Image.ImageSource = 'images.jpg';
            app.ComenzarButton = uibutton(app.EstacionamientodeVehiculosUIFigure, 'push');
            app.ComenzarButton.ButtonPushedFcn = createCallbackFcn(app, @ComenzarButtonPushed, true);
            app.ComenzarButton.Text = 'Comenzar';
            app.ManualButton = uibutton(app.EstacionamientodeVehiculosUIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.TextArea = uitextarea(app.EstacionamientodeVehiculosUIFigure);
            app.TextArea.Editable = 'off';
            app.SISTEMADEESTACIONAMIENTOLabel = uilabel(app.EstacionamientodeVehiculosUIFigure);
            app.SISTEMADEESTACIONAMIENTOLabel.Text = 'SISTEMA DE ESTACIONAMIENTO ';
            % Show the figure after all components are created
            app.EstacionamientodeVehiculosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SistemaEstacionamiento

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EstacionamientodeVehiculosUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EstacionamientodeVehiculosUIFigure)
        end
    end
end